classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MainviewTab                     matlab.ui.container.Tab
        MainGrid                        matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        MapGridLayout                   matlab.ui.container.GridLayout
        MapPanel                        matlab.ui.container.Panel
        TopInfoGridLayout               matlab.ui.container.GridLayout
        PolarisationControlGridLayout   matlab.ui.container.GridLayout
        PolarisationControlValuesGridLayout  matlab.ui.container.GridLayout
        EditpolarisationEditField       matlab.ui.control.NumericEditField
        EditpolarisationEditFieldLabel  matlab.ui.control.Label
        PolarisationcontrolLabel        matlab.ui.control.Label
        TrackerTargetAdditionalInfoGridLayout  matlab.ui.container.GridLayout
        TargetPositionGridLayout        matlab.ui.container.GridLayout
        GridLayout25_4                  matlab.ui.container.GridLayout
        TargetHeadingrelativetotrackerEditField  matlab.ui.control.NumericEditField
        HeadingrelativetotrackerEditFieldLabel  matlab.ui.control.Label
        TargetHeadingabsoluteEditField  matlab.ui.control.NumericEditField
        HeadingabsoluteEditFieldLabel   matlab.ui.control.Label
        TargetElevationEditField        matlab.ui.control.NumericEditField
        ElevationEditField_2Label       matlab.ui.control.Label
        TargetDistancestraightlineEditField  matlab.ui.control.NumericEditField
        DistancestraightlineEditFieldLabel  matlab.ui.control.Label
        TargetpositionLabel             matlab.ui.control.Label
        TrackerOrientationGridLayout    matlab.ui.container.GridLayout
        TrackerorientationandpositionLabel  matlab.ui.control.Label
        TrackerOrientationValuesGridLayout  matlab.ui.container.GridLayout
        TrackerPitchEditField           matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        TrackerRollEditField            matlab.ui.control.NumericEditField
        RollEditFieldLabel              matlab.ui.control.Label
        TrackerYawEditField             matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGridLayoutMain         matlab.ui.container.GridLayout
        LeftPanelGridLayout             matlab.ui.container.GridLayout
        TrackerControlGridLayout        matlab.ui.container.GridLayout
        TrackercontrolLabel             matlab.ui.control.Label
        TracklerControlButtonGridLayout  matlab.ui.container.GridLayout
        ResetTrackerButton              matlab.ui.control.Button
        ZerooutpolarisationButton       matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        ActivatedeactivatemotorsButton  matlab.ui.control.StateButton
        LocationSourceGridLayoutMain    matlab.ui.container.GridLayout
        LocationSourceGridLayout        matlab.ui.container.GridLayout
        TargetLocationSourceGridLayout  matlab.ui.container.GridLayout
        TargetNewDataSourceSwitchGridLayout  matlab.ui.container.GridLayout
        TargetLocationSourceSwitch      matlab.ui.control.Switch
        TargetNewDataGridLayout         matlab.ui.container.GridLayout
        TargetNewDataLamp               matlab.ui.control.Lamp
        NewdataLamp_2Label_2            matlab.ui.control.Label
        TargetPositionControlGridLayout  matlab.ui.container.GridLayout
        TargetStepSizeGridLayout        matlab.ui.container.GridLayout
        TargetStepSizeRadioButton       matlab.ui.container.ButtonGroup
        TargetStep1000RadioButton       matlab.ui.control.RadioButton
        TargetStep100RadioButton        matlab.ui.control.RadioButton
        TargetStep10RadioButton         matlab.ui.control.RadioButton
        TargetStep1RadioButton          matlab.ui.control.RadioButton
        TargetPositionLatLongAltControlGridLayout  matlab.ui.container.GridLayout
        TargetAltEditGridLayout         matlab.ui.container.GridLayout
        TargetAltEditField              matlab.ui.control.NumericEditField
        LONGEditField_2Label_5          matlab.ui.control.Label
        TargetPositionButtonsGridLayout  matlab.ui.container.GridLayout
        TargetPositionRight             matlab.ui.control.Button
        TargetPositionDown              matlab.ui.control.Button
        TargetPositionLeft              matlab.ui.control.Button
        TargetPositionUp                matlab.ui.control.Button
        TargetLongEditGridLayout        matlab.ui.container.GridLayout
        TargetLongEditField             matlab.ui.control.NumericEditField
        LONGEditField_2Label_4          matlab.ui.control.Label
        TargetLatEditGridLayout         matlab.ui.container.GridLayout
        TargetLatEditField              matlab.ui.control.NumericEditField
        LATEditField_2Label_3           matlab.ui.control.Label
        TrackerLocationSourceGridLayout  matlab.ui.container.GridLayout
        TrackerNewDataSourceSwitchGridLayout  matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        Tracker_new_data_lamp           matlab.ui.control.Lamp
        NewdataLampLabel_2              matlab.ui.control.Label
        Tracker_loc_source              matlab.ui.control.Switch
        TrackerPositionControlGridLayout  matlab.ui.container.GridLayout
        TrackerStepSizeGridLayout       matlab.ui.container.GridLayout
        TrackerStepSizeRadioButtonGroup  matlab.ui.container.ButtonGroup
        TrackerStep1000RadioButton      matlab.ui.control.RadioButton
        TrackerStep100RadioButton       matlab.ui.control.RadioButton
        TrackerStep10RadioButton        matlab.ui.control.RadioButton
        TrackerStep1RadioButton         matlab.ui.control.RadioButton
        TrackerPositionLatLongAltControlGridLayout  matlab.ui.container.GridLayout
        TrackerAltEditGridLayout        matlab.ui.container.GridLayout
        TrackerAltEditField             matlab.ui.control.NumericEditField
        ALTEditField_2Label             matlab.ui.control.Label
        TrackerPositionButtonsGridLayout  matlab.ui.container.GridLayout
        TrackerPositionRight            matlab.ui.control.Button
        TrackerPositionDown             matlab.ui.control.Button
        TrackerPositionLeft             matlab.ui.control.Button
        TrackerPositionUp               matlab.ui.control.Button
        TrackerLatGridLayout            matlab.ui.container.GridLayout
        TargerLatEditField              matlab.ui.control.NumericEditField
        LONGEditField_4Label            matlab.ui.control.Label
        TrackerLatEditGridLayout        matlab.ui.container.GridLayout
        TrackerLatEditField             matlab.ui.control.NumericEditField
        LATEditField_2Label_2           matlab.ui.control.Label
        TargetLabel                     matlab.ui.control.Label
        TrackerLabel                    matlab.ui.control.Label
        LocationsourcesLabel            matlab.ui.control.Label
        StartStopControlGridLayout      matlab.ui.container.GridLayout
        UPDATEMapDisplayTIMERButton_6   matlab.ui.control.Button
        StartStoptrackerinfodisplayButton  matlab.ui.control.StateButton
        MapDisplayPeriodInputGridLayout  matlab.ui.container.GridLayout
        MapDisplayPeriodInputEditField  matlab.ui.control.NumericEditField
        RefreshRateEditField_5Label     matlab.ui.control.Label
        StartStopmapdispayButton        matlab.ui.control.StateButton
        StartStoptrackingButton         matlab.ui.control.StateButton
        UPDATEInfoTIMERButton           matlab.ui.control.Button
        UPDATETrackingTIMERButton       matlab.ui.control.Button
        TrackerInfoPeriodInputGridLayout  matlab.ui.container.GridLayout
        TrackerInfoPeriodInputEditField  matlab.ui.control.NumericEditField
        RefreshRateEditField_4Label     matlab.ui.control.Label
        TrackerControlUpdatePeriodInputGridLayout  matlab.ui.container.GridLayout
        TrackerControlUpdatePeriodInputEditField  matlab.ui.control.NumericEditField
        RefreshRateEditField_6Label     matlab.ui.control.Label
        ConnectionGridLayout            matlab.ui.container.GridLayout
        TargetCOMGridLayout_3           matlab.ui.container.GridLayout
        SelectTargetsCOMportDropDown    matlab.ui.control.DropDown
        SelectTargetsCOMportDropDown_3Label  matlab.ui.control.Label
        GridLayout8_3                   matlab.ui.container.GridLayout
        TargetConnectLamp               matlab.ui.control.Lamp
        TargetDisconnectButton          matlab.ui.control.Button
        TargetConnectButton             matlab.ui.control.Button
        TargetRefreshButton             matlab.ui.control.Button
        GridLayout8_2                   matlab.ui.container.GridLayout
        TrackerConnectLamp              matlab.ui.control.Lamp
        TrackerDisconnectButton         matlab.ui.control.Button
        TrackerConnectButton            matlab.ui.control.Button
        TrackerRefreshButton            matlab.ui.control.Button
        BaseCOMGridLayout               matlab.ui.container.GridLayout
        SelectTrackersCOMportDropDown   matlab.ui.control.DropDown
        SelectTrackersCOMportDropDownLabel  matlab.ui.control.Label
        ManualcontrolTab                matlab.ui.container.Tab
        SettingsTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        TrackerPortWatchdogTimer 
        TargetPortWatchdogTimer
        TrackerPort
        TargetPort
        TrackerConnected = 0;
        TargetConnected = 0;

        TargetDisplayLocation = [50.1032042 14.3926031]
        TrackerDisplayLocation = [50.0982544 14.3885983]
        geomap_tracker
        geomap_target
        geomap_line
        geoAx
    end
    
    methods (Access = private)
        %% Functions to handle disconnecting and connecting TRACKER COM port
        function signalTrackerComDisconnected(app)
            app.TrackerConnectLamp.Color = 'R';
            app.Tracker_loc_source.Enable = "off";
            app.Tracker_loc_source.Value = "User";
            app.TrackerConnected = 0;
            app.SelectTrackersCOMportDropDown.Items = {''};
            app.SelectTrackersCOMportDropDown.Value = {''};
            app.SelectTrackersCOMportDropDown.Placeholder = 'Disconnected';
        end

        function signalTrackerComConnected(app)
            app.Tracker_loc_source.Enable = "on";
        end
        
        %% Function to ping tracker periodically

        function pingTrackerSerialPort(app,src,event)
            persistent wd  % so we can stop/delete it inside itself
            if isempty(wd)
                % grab the timer handle the first time
                timers = timerfind;  
                % assume it's the only one or the one you just started
                wd = timers(end);
            end
        
            try
                % do something trivial that errors if port is gone:
                app.TrackerPort.NumBytesAvailable;
                app.TrackerConnectLamp.Color = 'G';
                app.TrackerConnected = 1;
                app.signalTrackerComConnected
                % (you could also try flush(s) or write(s,uint8(0));)
            catch
                % port went away!
                app.signalTrackerComDisconnected
                stop(wd);
                delete(wd);
            end
        end

        %% Functions to handle disconnecting and connecting TRACKER COM port

        function signalTargetComDisconnected(app)
            app.TargetConnectLamp.Color = 'R';
            app.Target_loc_source.Enable = "off";
            app.Target_loc_source.Value = "User";
            app.TargetConnected = 0;
            app.SelectTargetCOMportDropDown.Items = {''};
            app.SelectTargetCOMportDropDown.Value = {''};
            app.SelectTargetCOMportDropDown.Placeholder = 'Disconnected';
        end

        function signalTargetComConnected(app)
            app.Target_loc_source.Enable = "on";
        end
        
        %% Function to ping target periodically
        function pingTargetSerialPort(app,src,event)
            persistent wd  % so we can stop/delete it inside itself
            if isempty(wd)
                % grab the timer handle the first time
                timers = timerfind;  
                % assume it's the only one or the one you just started
                wd = timers(end);
            end
        
            try
                % do something trivial that errors if port is gone:
                app.TargetPort.NumBytesAvailable;
                app.TargetConnectLamp.Color = 'G';
                app.TargetConnected = 1;
                app.signalTargetComConnected
                % (you could also try flush(s) or write(s,uint8(0));)
            catch
                % port went away!
                app.signalTargetComDisconnected
                stop(wd);
                delete(wd);
            end
        end


        function [arc_len, air_len, heading, base_elev_deg, target_elev_deg] = UpdateDistanceHeadingElevation(app, base, target)

            R = 6371000; %Earth's radius in metres

            %% Distance
            % Converting from deg to rad
            point1_rad = deg2rad(base);
            point2_rad = deg2rad(target);
            
            % Computing great angle between the points
            great_angle_rad = acos(sin(point1_rad(1)) * sin(point2_rad(1)) + cos(point1_rad(1)) * cos(point2_rad(1)) * cos(abs(point1_rad(2) - point2_rad(2))));
            
            % Adding asl height to earth's radius
            total_len_1 = R+point1_rad(3);
            total_len_2 = R+point2_rad(3);
            
            % Basic arc length (not useful)
            arc_len = R*great_angle_rad;
            
            % Distance between the points if you are an electromagnetic wave
            air_len = sqrt(total_len_1^2 + total_len_2^2 - 2*total_len_1*total_len_2*cos(great_angle_rad));


            %% Elevation at which you have to look at the other point
            beta_rad = asin(total_len_1*sin(great_angle_rad)/air_len);
            alpha_rad = pi - (great_angle_rad + beta_rad);
            
            base_elev_deg = 180/pi * (-pi/2 + alpha_rad);
            target_elev_deg = 180/pi * (-pi/2 + beta_rad);


            %% Heading computing
            % dlat = point2_rad(1) - point1_rad(1);
            dlon = point2_rad(2) - point1_rad(2);
            
            y = sin(dlon) * cos(point2_rad(1));
            x = cos(point1_rad(1)) * sin(point2_rad(1)) - sin(point1_rad(1)) * cos(point2_rad(1)) * cos(dlon);
            heading = atan2(y, x);
            
            % Convert heading to degrees
            heading = rad2deg(heading);
            
            % Normalize heading to 0-360 degrees
            if heading < 0
                heading = heading + 360;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.geoAx = geoaxes('Parent', app.MapPanel,'Position', [0.08 0.08 0.88 0.88]);
            geobasemap(app.geoAx, "streets");
            hold(app.geoAx, 'on');
            app.geomap_tracker = geoplot(app.geoAx, [app.TrackerDisplayLocation(1)], [app.TrackerDisplayLocation(2)], ...
                '-o', 'LineStyle', 'none','LineWidth', 2, 'MarkerSize', 8, 'Color', 'r');
            app.geomap_target = geoplot(app.geoAx, [app.TargetDisplayLocation(1)], [app.TargetDisplayLocation(2)], ...
                '-o', 'LineStyle', 'none', 'LineWidth', 2, 'MarkerSize', 8, 'Color', 'b');
            app.geomap_line = geoplot(app.geoAx, [app.TrackerDisplayLocation(1) app.TargetDisplayLocation(1)], [app.TrackerDisplayLocation(2) app.TargetDisplayLocation(2)], ...
                'Marker','none' , 'LineWidth', 2,"LineStyle", '--', 'MarkerSize', 8, 'Color', 'r');
            legend(app.geoAx, 'Base', 'Target', '')
        end

        % Callback function: not associated with a component
        function base_location_source_fcn(app, event)
            value = app.base_loc_source.Value;
            
        end

        % Callback function: not associated with a component
        function target_loc_sourceValueChanged(app, event)
            value = app.TargetLocationSourceSwitch.Value;
            switch value
                case "GPS"
                    app.target_LONGEditFieapp.panel_mapld.Enable = "off";
                    app.TargetLatEditField.Enable = "off";

                    app.TargetPositionLeft.Enable = "off";
                    app.TargetPositionRight.Enable = "off";
                    app.TargetPositionUp.Enable = "off";
                    app.TargetPositionDown.Enable = "off";

                    app.target_StepsizeButtonGroup.Enable = "off";
                case "User"
                    app.TargetLongEditField.Enable = "on";
                    app.TargetLatEditField.Enable = "on";

                    app.TargetPositionLeft.Enable = "on";
                    app.TargetPositionRight.Enable = "on";
                    app.TargetPositionUp.Enable = "on";
                    app.TargetPositionDown.Enable = "on";

                    app.target_StepsizeButtonGroup.Enable = "on";
            end
        end

        % Button pushed function: TrackerRefreshButton
        function TrackerRefreshButtonPushed(app, event)
            avalible_serial_ports = serialportlist;
            app.SelectTrackersCOMportDropDown.Items = avalible_serial_ports;
        end

        % Button pushed function: TrackerConnectButton
        function TrackerConnectButtonPushed(app, event)
            lastwarn('');
            try
                app.TrackerPort = TC_connect(app.SelectTrackersCOMportDropDown.Value);
                [warnMsg, warnId] = lastwarn;

                if ~isempty(warnMsg)
                    app.TrackerConnectLamp.Color='R';
                else
                    app.TrackerPortWatchdogTimer = timer( ...
                    'ExecutionMode',   'fixedSpacing', ...  % run regularly
                    'BusyMode','queue',...
                    'Period',          2.0,             ...  % every second
                    'TimerFcn',        @app.pingTrackerSerialPort ...
                    );
    
                    if strcmp(app.TrackerPortWatchdogTimer.Running,"off")
                        start(app.TrackerPortWatchdogTimer);
                    end
                end
            catch ME
                app.TrackerConnectLamp.Color='R';

            end
            

        end

        % Value changed function: EditpolarisationEditField
        function PolarisationUpdateCallback(app, event)
            value = app.EditpolarisationEditField.Value;
            
        end

        % Value changed function: Tracker_loc_source
        function Tracker_loc_sourceValueChanged(app, event)


            value = app.Tracker_loc_source.Value;
            switch value
                case "GPS"
                    app.TargerLatEditField.Enable = "off";
                    app.TrackerLatEditField.Enable = "off";

                    app.TrackerPositionLeft.Enable = "off";
                    app.TrackerPositionRight.Enable = "off";
                    app.TrackerPositionUp.Enable = "off";
                    app.TrackerPositionDown.Enable = "off";

                    app.TrackerStepSizeRadioButtonGroup.Enable = "off";
                case "User"
                    app.TargerLatEditField.Enable = "on";
                    app.TrackerLatEditField.Enable = "on";
                    %app.TrackerLatEditField.Editable = 'on';

                    app.TrackerPositionLeft.Enable = "on";
                    app.TrackerPositionRight.Enable = "on";
                    app.TrackerPositionUp.Enable = "on";
                    app.TrackerPositionDown.Enable = "on";

                    app.TrackerStepSizeRadioButtonGroup.Enable = "on";
            end
        end

        % Button pushed function: TargetRefreshButton
        function TargetRefreshButtonPushed(app, event)
            avalible_serial_ports = serialportlist;
            app.SelectTrackersCOMportDropDown.Items = avalible_serial_ports;
        end

        % Button pushed function: TargetConnectButton
        function TargetConnectButtonPushed(app, event)
            lastwarn('');
            try
                app.TargetPort = Target_connect(app.SelectTrackersCOMportDropDown.Value);
                [warnMsg, warnId] = lastwarn;

                if ~isempty(warnMsg)
                    app.TargetConnectLamp.Color='R';
                else
                    app.TargetPortWatchdogTimer = timer( ...
                    'ExecutionMode',   'fixedSpacing', ...  % run regularly
                    'BusyMode','queue',...
                    'Period',          2.0,             ...  % every second
                    'TimerFcn',        @app.pingTargetSerialPort ...
                    );
    
                    if strcmp(app.TargetPortWatchdogTimer.Running,"off")
                        start(app.TargetPortWatchdogTimer);
                    end
                end
            catch ME
                app.TrackerConnectLamp.Color='R';

            end
        end

        % Button pushed function: TrackerDisconnectButton
        function TrackerDisconnectButtonPushed(app, event)
            app.TrackerPort.delete
        end

        % Button pushed function: TargetDisconnectButton
        function TargetDisconnectButtonPushed(app, event)
            app.TargetPort.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.MainGrid = uigridlayout(app.MainviewTab);
            app.LeftPanel = uipanel(app.MainGrid);
            app.LeftPanelGridLayoutMain = uigridlayout(app.LeftPanel);
            app.LeftPanelGridLayout = uigridlayout(app.LeftPanelGridLayoutMain);
            app.ConnectionGridLayout = uigridlayout(app.LeftPanelGridLayout);
            app.BaseCOMGridLayout = uigridlayout(app.ConnectionGridLayout);
            app.SelectTrackersCOMportDropDownLabel = uilabel(app.BaseCOMGridLayout);
            app.SelectTrackersCOMportDropDownLabel.Text = 'Select Tracker''s COM port';
            app.SelectTrackersCOMportDropDown = uidropdown(app.BaseCOMGridLayout);
            app.SelectTrackersCOMportDropDown.Items = {};
            app.SelectTrackersCOMportDropDown.Placeholder = 'Press Refresh';
            app.SelectTrackersCOMportDropDown.Value = {};
            app.GridLayout8_2 = uigridlayout(app.ConnectionGridLayout);
            app.TrackerRefreshButton = uibutton(app.GridLayout8_2, 'push');
            app.TrackerRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @TrackerRefreshButtonPushed, true);
            app.TrackerRefreshButton.Text = 'Refresh';
            app.TrackerConnectButton = uibutton(app.GridLayout8_2, 'push');
            app.TrackerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @TrackerConnectButtonPushed, true);
            app.TrackerConnectButton.Text = 'Connect';
            app.TrackerDisconnectButton = uibutton(app.GridLayout8_2, 'push');
            app.TrackerDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @TrackerDisconnectButtonPushed, true);
            app.TrackerDisconnectButton.Text = 'Disconnect';
            app.TrackerConnectLamp = uilamp(app.GridLayout8_2);
            app.GridLayout8_3 = uigridlayout(app.ConnectionGridLayout);
            app.TargetRefreshButton = uibutton(app.GridLayout8_3, 'push');
            app.TargetRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @TargetRefreshButtonPushed, true);
            app.TargetRefreshButton.Text = 'Refresh';
            app.TargetConnectButton = uibutton(app.GridLayout8_3, 'push');
            app.TargetConnectButton.ButtonPushedFcn = createCallbackFcn(app, @TargetConnectButtonPushed, true);
            app.TargetConnectButton.Text = 'Connect';
            app.TargetDisconnectButton = uibutton(app.GridLayout8_3, 'push');
            app.TargetDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @TargetDisconnectButtonPushed, true);
            app.TargetDisconnectButton.Text = 'Disconnect';
            app.TargetConnectLamp = uilamp(app.GridLayout8_3);
            app.TargetCOMGridLayout_3 = uigridlayout(app.ConnectionGridLayout);
            app.SelectTargetsCOMportDropDown_3Label = uilabel(app.TargetCOMGridLayout_3);
            app.SelectTargetsCOMportDropDown_3Label.Text = 'Select Target''s COM port';
            app.SelectTargetsCOMportDropDown = uidropdown(app.TargetCOMGridLayout_3);
            app.SelectTargetsCOMportDropDown.Items = {};
            app.SelectTargetsCOMportDropDown.Placeholder = 'Press Refresh';
            app.SelectTargetsCOMportDropDown.Value = {};
            app.StartStopControlGridLayout = uigridlayout(app.LeftPanelGridLayout);
            app.TrackerControlUpdatePeriodInputGridLayout = uigridlayout(app.StartStopControlGridLayout);
            app.RefreshRateEditField_6Label = uilabel(app.TrackerControlUpdatePeriodInputGridLayout);
            app.RefreshRateEditField_6Label.Text = {'Refresh'; 'Rate'};
            app.TrackerControlUpdatePeriodInputEditField = uieditfield(app.TrackerControlUpdatePeriodInputGridLayout, 'numeric');
            app.TrackerControlUpdatePeriodInputEditField.Limits = [0 2];
            app.TrackerControlUpdatePeriodInputEditField.ValueDisplayFormat = '%11.4g s';
            app.TrackerControlUpdatePeriodInputEditField.Value = 1;
            app.TrackerInfoPeriodInputGridLayout = uigridlayout(app.StartStopControlGridLayout);
            app.RefreshRateEditField_4Label = uilabel(app.TrackerInfoPeriodInputGridLayout);
            app.RefreshRateEditField_4Label.Text = {'Refresh'; 'Rate'};
            app.TrackerInfoPeriodInputEditField = uieditfield(app.TrackerInfoPeriodInputGridLayout, 'numeric');
            app.TrackerInfoPeriodInputEditField.Limits = [0 10];
            app.TrackerInfoPeriodInputEditField.ValueDisplayFormat = '%11.4g s';
            app.TrackerInfoPeriodInputEditField.Value = 1;
            app.UPDATETrackingTIMERButton = uibutton(app.StartStopControlGridLayout, 'push');
            app.UPDATETrackingTIMERButton.Text = {'UPDATE'; 'TIMER'};
            app.UPDATEInfoTIMERButton = uibutton(app.StartStopControlGridLayout, 'push');
            app.UPDATEInfoTIMERButton.Text = {'UPDATE'; 'TIMER'};
            app.StartStoptrackingButton = uibutton(app.StartStopControlGridLayout, 'state');
            app.StartStoptrackingButton.Text = 'Start/Stop tracking';
            app.StartStopmapdispayButton = uibutton(app.StartStopControlGridLayout, 'state');
            app.StartStopmapdispayButton.Text = 'Start/Stop map dispay';
            app.MapDisplayPeriodInputGridLayout = uigridlayout(app.StartStopControlGridLayout);
            app.RefreshRateEditField_5Label = uilabel(app.MapDisplayPeriodInputGridLayout);
            app.RefreshRateEditField_5Label.Text = {'Refresh'; 'Rate'};
            app.MapDisplayPeriodInputEditField = uieditfield(app.MapDisplayPeriodInputGridLayout, 'numeric');
            app.MapDisplayPeriodInputEditField.Limits = [0 10];
            app.MapDisplayPeriodInputEditField.ValueDisplayFormat = '%11.4g s';
            app.MapDisplayPeriodInputEditField.Value = 1;
            app.StartStoptrackerinfodisplayButton = uibutton(app.StartStopControlGridLayout, 'state');
            app.StartStoptrackerinfodisplayButton.Text = {'Start/Stop tracker info'; 'display'};
            app.UPDATEMapDisplayTIMERButton_6 = uibutton(app.StartStopControlGridLayout, 'push');
            app.UPDATEMapDisplayTIMERButton_6.Text = {'UPDATE'; 'TIMER'};
            app.LocationSourceGridLayoutMain = uigridlayout(app.LeftPanelGridLayout);
            app.LocationSourceGridLayout = uigridlayout(app.LocationSourceGridLayoutMain);
            app.LocationsourcesLabel = uilabel(app.LocationSourceGridLayout);
            app.LocationsourcesLabel.Text = 'Location sources';
            app.TrackerLabel = uilabel(app.LocationSourceGridLayout);
            app.TrackerLabel.Text = 'Tracker';
            app.TargetLabel = uilabel(app.LocationSourceGridLayout);
            app.TargetLabel.Text = 'Target';
            app.TrackerLocationSourceGridLayout = uigridlayout(app.LocationSourceGridLayout);
            app.TrackerPositionControlGridLayout = uigridlayout(app.TrackerLocationSourceGridLayout);
            app.TrackerPositionLatLongAltControlGridLayout = uigridlayout(app.TrackerPositionControlGridLayout);
            app.TrackerLatEditGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.LATEditField_2Label_2 = uilabel(app.TrackerLatEditGridLayout);
            app.LATEditField_2Label_2.Enable = 'off';
            app.LATEditField_2Label_2.Text = 'LAT';
            app.TrackerLatEditField = uieditfield(app.TrackerLatEditGridLayout, 'numeric');
            app.TrackerLatEditField.Limits = [0 Inf];
            app.TrackerLatEditField.ValueDisplayFormat = '%2.7f N';
            app.TrackerLatEditField.AllowEmpty = 'on';
            app.TrackerLatEditField.Enable = 'off';
            app.TrackerLatEditField.Value = 50.1032042;
            app.TrackerLatGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.LONGEditField_4Label = uilabel(app.TrackerLatGridLayout);
            app.LONGEditField_4Label.Enable = 'off';
            app.LONGEditField_4Label.Text = 'LONG';
            app.TargerLatEditField = uieditfield(app.TrackerLatGridLayout, 'numeric');
            app.TargerLatEditField.Limits = [0 Inf];
            app.TargerLatEditField.ValueDisplayFormat = '%2.7f E';
            app.TargerLatEditField.AllowEmpty = 'on';
            app.TargerLatEditField.Enable = 'off';
            app.TargerLatEditField.Value = 14.3926031;
            app.TrackerPositionButtonsGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.TrackerPositionUp = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionUp.Enable = 'off';
            app.TrackerPositionUp.Text = '↑';
            app.TrackerPositionLeft = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionLeft.Enable = 'off';
            app.TrackerPositionLeft.Text = '←';
            app.TrackerPositionDown = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionDown.Enable = 'off';
            app.TrackerPositionDown.Text = '↓';
            app.TrackerPositionRight = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionRight.Enable = 'off';
            app.TrackerPositionRight.Text = '→';
            app.TrackerAltEditGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.ALTEditField_2Label = uilabel(app.TrackerAltEditGridLayout);
            app.ALTEditField_2Label.Enable = 'off';
            app.ALTEditField_2Label.Text = 'ALT';
            app.TrackerAltEditField = uieditfield(app.TrackerAltEditGridLayout, 'numeric');
            app.TrackerAltEditField.Limits = [0 Inf];
            app.TrackerAltEditField.ValueDisplayFormat = '%5.2f m a. s. l';
            app.TrackerAltEditField.AllowEmpty = 'on';
            app.TrackerAltEditField.Enable = 'off';
            app.TrackerAltEditField.Value = 219;
            app.TrackerStepSizeGridLayout = uigridlayout(app.TrackerPositionControlGridLayout);
            app.TrackerStepSizeRadioButtonGroup = uibuttongroup(app.TrackerStepSizeGridLayout);
            app.TrackerStepSizeRadioButtonGroup.Enable = 'off';
            app.TrackerStepSizeRadioButtonGroup.Title = 'Step size';
            app.TrackerStep1RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep1RadioButton.Text = '1x';
            app.TrackerStep1RadioButton.Value = true;
            app.TrackerStep10RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep10RadioButton.Text = '10x';
            app.TrackerStep100RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep100RadioButton.Text = '100x';
            app.TrackerStep1000RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep1000RadioButton.Text = '1000x';
            app.TrackerNewDataSourceSwitchGridLayout = uigridlayout(app.TrackerLocationSourceGridLayout);
            app.Tracker_loc_source = uiswitch(app.TrackerNewDataSourceSwitchGridLayout, 'slider');
            app.Tracker_loc_source.Items = {'GPS', 'User'};
            app.Tracker_loc_source.ValueChangedFcn = createCallbackFcn(app, @Tracker_loc_sourceValueChanged, true);
            app.Tracker_loc_source.Enable = 'off';
            app.Tracker_loc_source.Value = 'User';
            app.GridLayout18 = uigridlayout(app.TrackerNewDataSourceSwitchGridLayout);
            app.NewdataLampLabel_2 = uilabel(app.GridLayout18);
            app.NewdataLampLabel_2.Text = 'New data';
            app.Tracker_new_data_lamp = uilamp(app.GridLayout18);
            app.TargetLocationSourceGridLayout = uigridlayout(app.LocationSourceGridLayout);
            app.TargetPositionControlGridLayout = uigridlayout(app.TargetLocationSourceGridLayout);
            app.TargetPositionLatLongAltControlGridLayout = uigridlayout(app.TargetPositionControlGridLayout);
            app.TargetLatEditGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.LATEditField_2Label_3 = uilabel(app.TargetLatEditGridLayout);
            app.LATEditField_2Label_3.Enable = 'off';
            app.LATEditField_2Label_3.Text = 'LAT';
            app.TargetLatEditField = uieditfield(app.TargetLatEditGridLayout, 'numeric');
            app.TargetLatEditField.ValueDisplayFormat = '%2.7f N';
            app.TargetLatEditField.Enable = 'off';
            app.TargetLatEditField.Value = 50.0982544;
            app.TargetLongEditGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.LONGEditField_2Label_4 = uilabel(app.TargetLongEditGridLayout);
            app.LONGEditField_2Label_4.Enable = 'off';
            app.LONGEditField_2Label_4.Text = 'LONG';
            app.TargetLongEditField = uieditfield(app.TargetLongEditGridLayout, 'numeric');
            app.TargetLongEditField.ValueDisplayFormat = '%2.7f E';
            app.TargetLongEditField.Enable = 'off';
            app.TargetLongEditField.Value = 14.3885983;
            app.TargetPositionButtonsGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.TargetPositionUp = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionUp.Enable = 'off';
            app.TargetPositionUp.Text = '↑';
            app.TargetPositionLeft = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionLeft.Enable = 'off';
            app.TargetPositionLeft.Text = '←';
            app.TargetPositionDown = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionDown.Enable = 'off';
            app.TargetPositionDown.Text = '↓';
            app.TargetPositionRight = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionRight.Enable = 'off';
            app.TargetPositionRight.Text = '→';
            app.TargetAltEditGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.LONGEditField_2Label_5 = uilabel(app.TargetAltEditGridLayout);
            app.LONGEditField_2Label_5.Enable = 'off';
            app.LONGEditField_2Label_5.Text = 'ALT';
            app.TargetAltEditField = uieditfield(app.TargetAltEditGridLayout, 'numeric');
            app.TargetAltEditField.ValueDisplayFormat = '%5.2f m a. s. l';
            app.TargetAltEditField.Enable = 'off';
            app.TargetAltEditField.Value = 225;
            app.TargetStepSizeGridLayout = uigridlayout(app.TargetPositionControlGridLayout);
            app.TargetStepSizeRadioButton = uibuttongroup(app.TargetStepSizeGridLayout);
            app.TargetStepSizeRadioButton.Enable = 'off';
            app.TargetStepSizeRadioButton.Title = 'Step size';
            app.TargetStep1RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep1RadioButton.Text = '1x';
            app.TargetStep1RadioButton.Value = true;
            app.TargetStep10RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep10RadioButton.Text = '10x';
            app.TargetStep100RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep100RadioButton.Text = '100x';
            app.TargetStep1000RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep1000RadioButton.Text = '1000x';
            app.TargetNewDataSourceSwitchGridLayout = uigridlayout(app.TargetLocationSourceGridLayout);
            app.TargetNewDataGridLayout = uigridlayout(app.TargetNewDataSourceSwitchGridLayout);
            app.NewdataLamp_2Label_2 = uilabel(app.TargetNewDataGridLayout);
            app.NewdataLamp_2Label_2.Text = 'New data';
            app.TargetNewDataLamp = uilamp(app.TargetNewDataGridLayout);
            app.TargetLocationSourceSwitch = uiswitch(app.TargetNewDataSourceSwitchGridLayout, 'slider');
            app.TargetLocationSourceSwitch.Items = {'GPS', 'User'};
            app.TargetLocationSourceSwitch.Enable = 'off';
            app.TargetLocationSourceSwitch.Value = 'User';
            app.TrackerControlGridLayout = uigridlayout(app.LeftPanelGridLayout);
            app.TracklerControlButtonGridLayout = uigridlayout(app.TrackerControlGridLayout);
            app.ActivatedeactivatemotorsButton = uibutton(app.TracklerControlButtonGridLayout, 'state');
            app.ActivatedeactivatemotorsButton.Text = 'Activate/deactivate motors';
            app.CalibrateButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.ZerooutpolarisationButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.ZerooutpolarisationButton.Text = 'Zero out polarisation';
            app.ResetTrackerButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.ResetTrackerButton.Text = 'Reset Tracker';
            app.TrackercontrolLabel = uilabel(app.TrackerControlGridLayout);
            app.TrackercontrolLabel.Text = 'Tracker control';
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.TopInfoGridLayout = uigridlayout(app.GridLayout3);
            app.TrackerTargetAdditionalInfoGridLayout = uigridlayout(app.TopInfoGridLayout);
            app.TrackerOrientationGridLayout = uigridlayout(app.TrackerTargetAdditionalInfoGridLayout);
            app.TrackerOrientationValuesGridLayout = uigridlayout(app.TrackerOrientationGridLayout);
            app.YawEditFieldLabel = uilabel(app.TrackerOrientationValuesGridLayout);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.TrackerYawEditField = uieditfield(app.TrackerOrientationValuesGridLayout, 'numeric');
            app.TrackerYawEditField.ValueDisplayFormat = '%.3f°';
            app.RollEditFieldLabel = uilabel(app.TrackerOrientationValuesGridLayout);
            app.RollEditFieldLabel.Text = 'Roll';
            app.TrackerRollEditField = uieditfield(app.TrackerOrientationValuesGridLayout, 'numeric');
            app.TrackerRollEditField.ValueDisplayFormat = '%.3f °';
            app.PitchEditFieldLabel = uilabel(app.TrackerOrientationValuesGridLayout);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.TrackerPitchEditField = uieditfield(app.TrackerOrientationValuesGridLayout, 'numeric');
            app.TrackerPitchEditField.ValueDisplayFormat = '%.3f °';
            app.TrackerorientationandpositionLabel = uilabel(app.TrackerOrientationGridLayout);
            app.TrackerorientationandpositionLabel.Text = 'Tracker orientation and position';
            app.TargetPositionGridLayout = uigridlayout(app.TrackerTargetAdditionalInfoGridLayout);
            app.TargetpositionLabel = uilabel(app.TargetPositionGridLayout);
            app.TargetpositionLabel.Text = 'Target position';
            app.GridLayout25_4 = uigridlayout(app.TargetPositionGridLayout);
            app.DistancestraightlineEditFieldLabel = uilabel(app.GridLayout25_4);
            app.DistancestraightlineEditFieldLabel.Text = 'Distance (straight line)';
            app.TargetDistancestraightlineEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetDistancestraightlineEditField.ValueDisplayFormat = '%.3f m';
            app.ElevationEditField_2Label = uilabel(app.GridLayout25_4);
            app.ElevationEditField_2Label.Text = 'Elevation';
            app.TargetElevationEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetElevationEditField.ValueDisplayFormat = '%.3f m';
            app.HeadingabsoluteEditFieldLabel = uilabel(app.GridLayout25_4);
            app.HeadingabsoluteEditFieldLabel.Text = 'Heading (absolute)';
            app.TargetHeadingabsoluteEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetHeadingabsoluteEditField.ValueDisplayFormat = '%.3f °';
            app.HeadingrelativetotrackerEditFieldLabel = uilabel(app.GridLayout25_4);
            app.HeadingrelativetotrackerEditFieldLabel.Text = 'Heading (relative to tracker)';
            app.TargetHeadingrelativetotrackerEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetHeadingrelativetotrackerEditField.ValueDisplayFormat = '%.3f °';
            app.PolarisationControlGridLayout = uigridlayout(app.TopInfoGridLayout);
            app.PolarisationcontrolLabel = uilabel(app.PolarisationControlGridLayout);
            app.PolarisationcontrolLabel.Text = 'Polarisation control';
            app.PolarisationControlValuesGridLayout = uigridlayout(app.PolarisationControlGridLayout);
            app.EditpolarisationEditFieldLabel = uilabel(app.PolarisationControlValuesGridLayout);
            app.EditpolarisationEditFieldLabel.Text = 'Edit polarisation';
            app.EditpolarisationEditField = uieditfield(app.PolarisationControlValuesGridLayout, 'numeric');
            app.EditpolarisationEditField.Limits = [0 360];
            app.EditpolarisationEditField.ValueDisplayFormat = '%.1d °';
            app.EditpolarisationEditField.ValueChangedFcn = createCallbackFcn(app, @PolarisationUpdateCallback, true);
            app.MapGridLayout = uigridlayout(app.GridLayout3);
            app.MapPanel = uipanel(app.MapGridLayout);
            app.MapPanel.Title = 'Panel';
            app.ManualcontrolTab = uitab(app.TabGroup);
            app.ManualcontrolTab.Title = 'Manual control';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end